classdef NR5G < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ParametrosTab                 matlab.ui.container.Tab
        SimulateTimeButton            matlab.ui.control.Button
        ExportParametersCheckBox      matlab.ui.control.CheckBox
        CNNPhaseCompensationCheckBox  matlab.ui.control.CheckBox
        CNNModel2Label                matlab.ui.control.Label
        CNNModel1Label                matlab.ui.control.Label
        CNNModel1DropDown             matlab.ui.control.DropDown
        CNNModel2DropDown             matlab.ui.control.DropDown
        TypeVelocityDropDown          matlab.ui.control.DropDown
        TypeVelocityDropDownLabel     matlab.ui.control.Label
        Label_end_vel                 matlab.ui.control.Label
        EditField_vel_step            matlab.ui.control.NumericEditField
        EditField_vel_init            matlab.ui.control.NumericEditField
        Knob_Vel                      matlab.ui.control.Knob
        VelEndLabel                   matlab.ui.control.Label
        StepLabel_2                   matlab.ui.control.Label
        VelInitLabel                  matlab.ui.control.Label
        VelocityKmhLabel              matlab.ui.control.Label
        Label_end_snr                 matlab.ui.control.Label
        EditField_SNR_step            matlab.ui.control.NumericEditField
        EditField_SNRinit             matlab.ui.control.NumericEditField
        Knob_SNR                      matlab.ui.control.Knob
        SNREndLabel                   matlab.ui.control.Label
        StepLabel                     matlab.ui.control.Label
        SNRInitLabel                  matlab.ui.control.Label
        FramesLabel                   matlab.ui.control.Label
        LoadImageButton               matlab.ui.control.Button
        FramesNumberEditField         matlab.ui.control.EditField
        DataDropDown                  matlab.ui.control.DropDown
        DataDropDownLabel             matlab.ui.control.Label
        TypeSNRDropDown               matlab.ui.control.DropDown
        TypeSNRDropDownLabel          matlab.ui.control.Label
        SNRdBLabel                    matlab.ui.control.Label
        DataTrasmissionLabel          matlab.ui.control.Label
        SimulateSimpleButton          matlab.ui.control.Button
        EstimationLabel               matlab.ui.control.Label
        Denoising2CheckBox            matlab.ui.control.CheckBox
        Denoising1CheckBox            matlab.ui.control.CheckBox
        PracticalCheckBox             matlab.ui.control.CheckBox
        AutoencoderCheckBox           matlab.ui.control.CheckBox
        CNNModel2CheckBox             matlab.ui.control.CheckBox
        CNNModel1CheckBox             matlab.ui.control.CheckBox
        ModulationDropDown            matlab.ui.control.DropDown
        ModulationDropDownLabel       matlab.ui.control.Label
        ChannelProfileDropDown        matlab.ui.control.DropDown
        ChannelProfileDropDownLabel   matlab.ui.control.Label
        TimeSimulationTab             matlab.ui.container.Tab
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        Model3_TimeLabel              matlab.ui.control.Label
        Model2_TimeLabel              matlab.ui.control.Label
        Model1_TimeLabel              matlab.ui.control.Label
        Model3_EVMLabel               matlab.ui.control.Label
        Model2_EVMLabel               matlab.ui.control.Label
        Model1_EVMLabel               matlab.ui.control.Label
        Model3_MSELabel               matlab.ui.control.Label
        Model2_MSELabel               matlab.ui.control.Label
        Model1_MSELabel               matlab.ui.control.Label
        Model3Label                   matlab.ui.control.Label
        Model2Label                   matlab.ui.control.Label
        Model1Label                   matlab.ui.control.Label
        StartButton                   matlab.ui.control.Button
        TimeLabel                     matlab.ui.control.Label
        EVMLabel                      matlab.ui.control.Label
        MSELabel                      matlab.ui.control.Label
        Grafica3DropDown              matlab.ui.control.DropDown
        Grafica3DropDownLabel         matlab.ui.control.Label
        Grafica2DropDown              matlab.ui.control.DropDown
        Grafica2DropDownLabel         matlab.ui.control.Label
        Grafica1DropDown              matlab.ui.control.DropDown
        Grafica1DropDownLabel         matlab.ui.control.Label
        Titulo_TimeLabel              matlab.ui.control.Label
        Graph4                        matlab.ui.control.UIAxes
        Graph3                        matlab.ui.control.UIAxes
        Graph2                        matlab.ui.control.UIAxes
        Graph1                        matlab.ui.control.UIAxes
        Metricas                      matlab.ui.container.Tab
        ClearUnique                   matlab.ui.control.Button
        GraphUnique                   matlab.ui.control.Button
        DropDown_2                    matlab.ui.control.DropDown
        GraphLabel                    matlab.ui.control.Label
        StyleGraphLabel               matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
        GraphTypeLabel                matlab.ui.control.Label
        SelectFileLabel               matlab.ui.control.Label
        SelectFileDropDown            matlab.ui.control.DropDown
        GraphTypeDropDown             matlab.ui.control.DropDown
        GraphBER                      matlab.ui.control.Button
        GraphTIME                     matlab.ui.control.Button
        GraphEVM                      matlab.ui.control.Button
        GraphMSE                      matlab.ui.control.Button
        ClearBER                      matlab.ui.control.Button
        ClearTIME                     matlab.ui.control.Button
        ClearEVM                      matlab.ui.control.Button
        ClearMSE                      matlab.ui.control.Button
        Unique                        matlab.ui.control.UIAxes
        TIME                          matlab.ui.control.UIAxes
        MSE                           matlab.ui.control.UIAxes
        BER                           matlab.ui.control.UIAxes
        EVM                           matlab.ui.control.UIAxes
        RealTimeTab                   matlab.ui.container.Tab
        ModulationDropDown_2          matlab.ui.control.DropDown
        ModulationDropDown_2Label     matlab.ui.control.Label
        Button                        matlab.ui.control.StateButton
        StopButton                    matlab.ui.control.Button
        SimulateButton                matlab.ui.control.Button
        CNNModelLabel                 matlab.ui.control.Label
        CNNModel1DropDown_2           matlab.ui.control.DropDown
        EstimationTypeDropDown        matlab.ui.control.DropDown
        EstimationTypeDropDownLabel   matlab.ui.control.Label
        SNRdBKnob                     matlab.ui.control.Knob
        SNRdBKnobLabel                matlab.ui.control.Label
        UserVelocityKmhKnob           matlab.ui.control.Knob
        UserVelocityKmhKnobLabel      matlab.ui.control.Label
        DropDown_3                    matlab.ui.control.DropDown
        ChannelTypeLabel              matlab.ui.control.Label
        Graph1_4                      matlab.ui.control.UIAxes
        Graph1_3                      matlab.ui.control.UIAxes
        Graph1_2                      matlab.ui.control.UIAxes
        ContextMenu                   matlab.ui.container.ContextMenu
        Menu                          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
        ContextMenu2                  matlab.ui.container.ContextMenu
        Menu_2                        matlab.ui.container.Menu
        Menu2_2                       matlab.ui.container.Menu
        ContextMenu3                  matlab.ui.container.ContextMenu
        Menu_3                        matlab.ui.container.Menu
        Menu2_3                       matlab.ui.container.Menu
    end


    properties (Access = private)

        
    end

    properties (Access = public)
        Pam_sim = [];% Parametros de la simulacion
        Parameters =[];
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Inicializacion de todas las variables
        
            
            app.Parameters.marks = ["o","pentagram","square","diamond","^","+","hexagram",".","x","_","|","square","diamond","^","v","<",">","*"];
            app.Parameters.lines = ["-","--",":","-.","-","--",":","-."];

            %Initialization data 
            app = Init_Parameters(app);
                
            
        end

        % Button pushed function: GraphBER
        function GraphBERPushed(app, event)
            hold(app.BER,"on");
            try
                %% ----------------------- BER Real Time----------------------------------
                if length(app.Parameters.SNR_Recorridas ) ~= length(app.Parameters.Mat_Lineal_BER)
                    app.Parameters.SNR_Recorridas = app.Parameters.SNR_Recorridas(2:end);
                end

                for i = 1:length(app.Parameters.models)
                    semilogy(app.BER,app.Parameters.SNR_Recorridas,app.Parameters.("Mat_"+string(app.Parameters.models{1,i})+"_BER"),LineWidth=1.5)
                end
                legend(app.BER,app.Parameters.models);

            catch error
                message = error.message;
                id = error.identifier
                if (strcmp(id,'MATLAB:nonExistentField'))
                    uialert(app.UIFigure,['There is not data to plot '],"Invalid File","Icon","info");
                end

            end
        end

        % Size changed function: Metricas
        function MetricasSizeChanged(app, event)
            %poition = app.Metricas.Position;

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)

        end

        % Button pushed function: ClearBER
        function ClearBERPushed(app, event)
            hold(app.BER,"off");
            cla(app.BER)
            app.BER.XLimMode = "auto";
            app.BER.YLimMode = "auto";
        end

        % Button pushed function: ClearEVM
        function ClearEVMPushed(app, event)
            hold(app.EVM,"off");
            cla(app.EVM)
            app.EVM.XLimMode = "auto";
            app.EVM.YLimMode = "auto";
        end

        % Button pushed function: GraphEVM
        function GraphEVMPushed(app, event)
            try
                hold(app.EVM,"on");
                %% ----------------------- EVM Real Time----------------------------------
                for i = 1:length(app.Parameters.models)
                    plot(app.EVM,app.Parameters.SNR_Recorridas,app.Parameters.("Mat_"+string(app.Parameters.models{1,i})+"_EVM"))
                end
                legend(app.EVM,app.Parameters.models)
            catch error
                message = error.message;
                id = error.identifier
                if (strcmp(id,'MATLAB:nonExistentField'))
                    uialert(app.UIFigure,['There is not data to plot '],"Invalid File","Icon","info");
                end

            end
        end

        % Button pushed function: GraphTIME
        function GraphTIMEPushed(app, event)

        end

        % Button pushed function: ClearTIME
        function ClearTIMEPushed(app, event)
            hold(app.TIME,"off");
            cla(app.TIME)
            app.TIME.XLimMode = "auto";
            app.TIME.YLimMode = "auto";          
        end

        % Button pushed function: ClearMSE
        function ClearMSEPushed(app, event)
            hold(app.MSE,"off");
            cla(app.MSE)
            app.MSE.XLimMode = "auto";
            app.MSE.YLimMode = "auto";
        end

        % Button pushed function: GraphMSE
        function GraphMSEPushed(app, event)
            try
                hold(app.MSE,"on");
                % ----------------------- MSE Real Time----------------------------------
                for i = 1:length(app.Parameters.models)
                    if strcmp(app.Parameters.models{1,i},"Perfect")
                        app.Parameters.("Mat_"+string(app.Parameters.models{1,i})+"_MSE") = zeros(size(app.Parameters.("Mat_"+string(app.Parameters.models{1,1})+"_MSE")));
                    end
                    semilogy(app.MSE,app.Parameters.SNR_Recorridas,app.Parameters.("Mat_"+string(app.Parameters.models{1,i})+"_MSE"))
                end
                legend(app.MSE,app.Parameters.models)
            catch error
                message = error.message;
                id = error.identifier
                if (strcmp(id,'MATLAB:nonExistentField'))
                    uialert(app.UIFigure,['There is not data to plot '],"Invalid File","Icon","info");
                end

            end
        end

        % Button pushed function: SimulateSimpleButton
        function SimulateSimpleButtonPushed(app, event)
            % Basic Simulation in function of the input parameters
            app = Complet_Simulation(app);
            
        end

        % Drop down opening function: TypeSNRDropDown
        function TypeSNRDropDownOpening(app, event)
            selection = app.TypeSNRDropDown.Value;
            switch selection
                case "SNR Static"
                    app.Pam_sim.SNR_STATIC = true;
                case "SNR Interval"
                    app.Pam_sim.SNR_STATIC = false;
            end
        end

        % Value changed function: TypeSNRDropDown
        function TypeSNRDropDownValueChanged(app, event)
            selection = app.TypeSNRDropDown.Value;
            switch selection
                case "SNR Static"
                    app.Pam_sim.SNR_STATIC = true;
                    app.SNREndLabel.Visible  = "off";
                    app.StepLabel.Visible = "off";
                    app.Knob_SNR.Visible = "off";
                    app.EditField_SNR_step.Visible = "off";
                    app.Label_end_snr.Visible ="off";

                case "SNR Interval"
                    app.SNREndLabel.Visible  = "on";
                    app.StepLabel.Visible = "on";
                    app.Knob_SNR.Visible = "on";
                    app.EditField_SNR_step.Visible = "on";
                    app.Label_end_snr.Visible ="on";
                    app.Pam_sim.SNR_STATIC = false;
            end

        end

        % Value changed function: ModulationDropDown
        function ModulationDropDownValueChanged(app, event)
            value = app.ModulationDropDown.Value;
            switch value
                case "QPSK"
                    app.Pam_sim.Modulation = "QPSK";
                case "16QAM"
                    app.Pam_sim.Modulation = "16QAM";
                case "64QAM"
                    app.Pam_sim.Modulation = "64QAM";
                case "256QAM"
                    app.Pam_sim.Modulation = "256QAM";
            end
        end

        % Value changed function: ChannelProfileDropDown
        function ChannelProfileDropDownValueChanged(app, event)
            value = app.ChannelProfileDropDown.Value;
            switch value
                case "TDL-A"
                    app.Pam_sim.Channel = "TDL-A";
                case "TDL-B"
                    app.Pam_sim.Channel = "TDL-B";
                case "TDL-C"
                    app.Pam_sim.Channel = "TDL-C";
                case "TDL-D"
                    app.Pam_sim.Channel = "TDL-D";
                case "TDL-E"
                    app.Pam_sim.Channel = "TDL-E";

            end

        end

        % Value changed function: CNNModel1CheckBox
        function CNNModel1CheckBoxValueChanged(app, event)

            sel = app.CNNModel1CheckBox.Value;
            app.Pam_sim.CNNEstimation = sel ;
            if sel
                app.CNNModel1DropDown.Visible = "on";
                app.CNNModel1Label.Visible = "on";
            else
                app.CNNModel1DropDown.Visible = "off";
                app.CNNModel1Label.Visible = "off";
            end
        end

        % Value changed function: CNNModel2CheckBox
        function CNNModel2CheckBoxValueChanged(app, event)

            sel = app.CNNModel2CheckBox.Value;
            app.Pam_sim.CNNEstimation_2 = sel ;
            if sel
                app.CNNModel2DropDown.Visible = "on";
                app.CNNModel2Label.Visible = "on";
            else
                app.CNNModel2DropDown.Visible = "off";
                app.CNNModel2Label.Visible = "off";
            end

        end

        % Value changed function: Denoising2CheckBox
        function Denoising2CheckBoxValueChanged(app, event)
            app.Pam_sim.Denoising_Estimation_resta = app.Denoising2CheckBox.Value;

        end

        % Value changed function: Denoising1CheckBox
        function Denoising1CheckBoxValueChanged(app, event)
            app.Pam_sim.Denoising_Estimation = app.Denoising1CheckBox.Value;

        end

        % Value changed function: PracticalCheckBox
        function PracticalCheckBoxValueChanged(app, event)
            app.Pam_sim.EstimacionPractica = app.PracticalCheckBox.Value;

        end

        % Value changed function: AutoencoderCheckBox
        function AutoencoderCheckBoxValueChanged(app, event)
            app.Pam_sim.Autoencoder_Estimation = app.AutoencoderCheckBox.Value;
        end

        % Callback function
        function DropDownOpening(app, event)

        end

        % Callback function
        function DropDownValueChanged(app, event)

        end

        % Value changed function: DataDropDown
        function DataDropDownValueChanged(app, event)
            value = app.DataDropDown.Value;
            switch value
                case "Frames"
                    app.FramesLabel.Text = "Frames";
                    app.FramesLabel.Visible ="on";
                    app.LoadImageButton.Visible = "off";
                    app.FramesNumberEditField.Visible = "on";
                    app.Pam_sim.Transmision_IMG = false;
                    app.Pam_sim.Transmision_Bits = false;
                case "Bits"
                    app.FramesLabel.Text = "Bits";
                    app.FramesLabel.Visible ="on";
                    app.LoadImageButton.Visible = "off";
                    app.FramesNumberEditField.Visible = "on";
                    app.Pam_sim.Transmision_IMG = false;
                    app.Pam_sim.Transmision_Bits = true;
                case "Image"
                    app.FramesLabel.Visible ="off";
                    app.FramesNumberEditField.Visible = "off";
                    app.LoadImageButton.Visible = "on";
                    app.Pam_sim.Transmision_IMG = true;
                    app.Pam_sim.Transmision_Bits = false;
                otherwise
            end
        end

        % Callback function
        function DataDropDownOpening(app, event)

        end

        % Value changed function: FramesNumberEditField
        function FramesNumberEditFieldValueChanged(app, event)
            value = str2double(app.FramesNumberEditField.Value);
            if value ==0
                uialert(app.UIFigure,"Number must be higger than 0","Invalid File");
            end
            switch app.DataDropDown.Value
                case "Frames"
                    app.Pam_sim.Num_Frames = value;
                case "Bits"
                    app.Pam_sim.Bits_Deseados = value;
                otherwise
            end

        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [file, path] = uigetfile(filterspec);
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(path))
                app.Pam_sim.Path_IMG = [path file];
                uialert(app.UIFigure,"Image Uploaded","Imge","Icon","success");
            end
        end

        % Callback function
        function TypeSNRDropDownOpening2(app, event)

        end

        % Value changed function: Knob_SNR
        function Knob_SNRValueChanged(app, event)
            value = app.Knob_SNR.Value;
            app.Label_end_snr.Text =string(value);
            app.Pam_sim.SNR_max = value;
        end

        % Value changed function: EditField_SNRinit
        function EditField_SNRinitValueChanged(app, event)
            value = app.EditField_SNRinit.Value;
            app.Pam_sim.SNR_Init = value;
        end

        % Value changed function: EditField_SNR_step
        function EditField_SNR_stepValueChanged(app, event)
            value = app.EditField_SNR_step.Value;
            app.Pam_sim.SNR_intervalos = value;
        end

        % Value changed function: Knob_Vel
        function Knob_VelValueChanged(app, event)
            value = app.Knob_Vel.Value;
            app.Pam_sim.Vel_end = app.Knob_Vel.Value;
            app.Label_end_vel.Text = string(value);

            
        end

        % Value changed function: EditField_vel_init
        function EditField_vel_initValueChanged(app, event)
            app.Pam_sim.Vel_init = app.EditField_vel_init.Value;
        end

        % Value changed function: EditField_vel_step
        function EditField_vel_stepValueChanged(app, event)
            app.Pam_sim.Vel_step = app.EditField_vel_step.Value;
            
        end

        % Value changed function: TypeVelocityDropDown
        function TypeVelocityDropDownValueChanged(app, event)
            value = app.TypeVelocityDropDown.Value;
            switch value

                case "Vel Static"
                    app.Pam_sim.Vel_sim_Estatic = true;
                    app.Label_end_vel.Visible = "off";

                    app.VelEndLabel.Visible  = "off";
                    app.StepLabel_2.Visible = "off";
                    app.Knob_Vel.Visible = "off";
                    app.EditField_vel_step.Visible = "off";


                case "Vel Interval"
                    app.Pam_sim.Vel_sim_Estatic = false;
                    app.Label_end_vel.Visible = "on";

                    app.VelEndLabel.Visible  = "on";
                    app.StepLabel_2.Visible = "on";
                    app.Knob_Vel.Visible = "on";
                    app.EditField_vel_step.Visible = "on";
                otherwise
                    
            end
            
        end

        % Value changed function: CNNModel2DropDown
        function CNNModel2DropDownValueChanged(app, event)
            app.Pam_sim.CNNmodel_2 = app.CNNModel2DropDown.Value;
            
        end

        % Value changed function: CNNModel1DropDown
        function CNNModel1DropDownValueChanged(app, event)
            app.Pam_sim.CNNmodel_1 = app.CNNModel1DropDown.Value;


        end

        % Value changed function: CNNPhaseCompensationCheckBox
        function CNNPhaseCompensationCheckBoxValueChanged(app, event)
            app.Pam_sim.Compensacion_Fase = app.CNNPhaseCompensationCheckBox.Value;

        end

        % Value changed function: ExportParametersCheckBox
        function ExportParametersCheckBoxValueChanged(app, event)
            app.Pam_sim.Save_Variables  = app.ExportParametersCheckBox.Value;
        end

        % Value changed function: GraphTypeDropDown
        function GraphTypeDropDownValueChanged(app, event)
            value = app.GraphTypeDropDown.Value;
            switch value
                case "Current Simulation"
                    app.SelectFileDropDown.Visible = "off";
                    app.SelectFileLabel.Visible = "off";
                    app.Parameters = app.Pam_sim;
                case "Load File"

                    Files = dir('Outputs/*.mat');
                    numfiles = length(Files);
                    mydata = cell(1, numfiles);
                    for k = 1:numfiles
                        mydata{k} = Files(k).name;
                    end
                    app.SelectFileDropDown.Visible = "on";
                    app.SelectFileLabel.Visible = "on";
                    app.SelectFileDropDown.Items = mydata;
                  
            end
        end

        % Value changed function: SelectFileDropDown
        function SelectFileDropDownValueChanged(app, event)
            try
                value = app.SelectFileDropDown.Value;
                app.Parameters = load(value).Parameters;
                uialert(app.UIFigure,[value," Uploaded"],"Matrix","Icon","success");
            catch error
                uialert(app.UIFigure,['  There was an error! The message was:   ',error.message, ' the identifier was: ',error.identifier,],"Invalid File","Icon","error");
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged2(app, event)
            value = app.DropDown.Value;
            switch value
                case "Unique"
                    ClearBERPushed(app, event);
                    ClearEVMPushed(app, event);
                    ClearMSEPushed(app, event);
                    ClearTIMEPushed(app, event);
                    legend(app.BER,"off");
                    legend(app.MSE,"off");
                    legend(app.EVM,"off");
                    legend(app.TIME,"off");

                    app.GraphBER.Visible = "off";
                    app.GraphTIME.Visible = "off";
                    app.GraphMSE.Visible = "off";
                    app.GraphEVM.Visible = "off";

                    app.ClearBER.Visible = "off";
                    app.ClearTIME.Visible = "off";
                    app.ClearMSE.Visible = "off";
                    app.ClearEVM.Visible = "off";

                    app.BER.Visible  = "off";
                    app.TIME.Visible = "off";
                    app.MSE.Visible  = "off";
                    app.EVM.Visible  = "off";
                    
                    app.ClearUnique.Visible = "on";
                    app.GraphUnique.Visible = "on";
                    app.Unique.Visible = "on";
                    app.DropDown_2.Visible = "on";
                    app.GraphLabel.Visible = "on";


                case "Multiple"
                    ClearUniqueButtonPushed(app, event);
                    legend(app.Unique,"off");
                    app.ClearUnique.Visible = "off";
                    app.GraphUnique.Visible = "off";
                    app.Unique.Visible = "off";
                    app.DropDown_2.Visible = "off";
                    app.GraphLabel.Visible = "off";

                    app.GraphBER.Visible = "on";
                    app.GraphTIME.Visible = "on";
                    app.GraphMSE.Visible = "on";
                    app.GraphEVM.Visible = "on";

                    app.ClearBER.Visible = "on";
                    app.ClearTIME.Visible = "on";
                    app.ClearMSE.Visible = "on";
                    app.ClearEVM.Visible = "on";

                    app.BER.Visible  = "on";
                    app.TIME.Visible = "on";
                    app.MSE.Visible  = "on";
                    app.EVM.Visible  = "on";


            end


        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            ClearUniqueButtonPushed(app, event);
            legend(app.Unique,"off");
             value = app.DropDown_2.Value;
            switch value
                case "BER vs SNR"
                    app.Pam_sim.Graph_type = "BER";

                    app.Unique.Title.String = "Bit Error Rate";
                    app.Unique.YLabel.String = "BER";
                    app.Unique.XLabel.String = "SNR(dB)";
                case "EVM vs SNR"
                    app.Pam_sim.Graph_type = "EVM";

                    app.Unique.Title.String = "Error Vector Machine";
                    app.Unique.YLabel.String = "EVM(%)";
                    app.Unique.XLabel.String = "SNR(dB)";
                case "MSE vs SNR"
                    app.Pam_sim.Graph_type = "MSE";
                    app.Unique.Title.String = "Mean Square Error";
                    app.Unique.YLabel.String = "MSE";
                    app.Unique.XLabel.String = "SNR(dB)";
                case "Time vs SNR"
                    app.Pam_sim.Graph_type = "Time";
                    app.Unique.XLabel.String = "SNR(dB)";
            end
            
        end

        % Button pushed function: GraphUnique
        function GraphUniqueButtonPushed(app, event)
            try

                hold(app.Unique,"on");


                if length(app.Parameters.SNR_Recorridas ) ~= length(app.Parameters.Mat_Lineal_BER)
                    app.Parameters.SNR_Recorridas = app.Parameters.SNR_Recorridas(2:end);
                end


                switch app.Pam_sim.Graph_type
                    case "BER"
                        app.Unique.YScale = "log";
                        for i = 1:length(app.Parameters.models)
                            semilogy(app.Unique,app.Parameters.SNR_Recorridas,app.Parameters.("Mat_"+string(app.Parameters.models{1,i})+"_BER"),LineWidth=1.1);
                        end

                    case "EVM"
                        app.Unique.YScale = "linear";
                        for i = 1:length(app.Parameters.models)
                            plot(app.Unique,app.Parameters.SNR_Recorridas,app.Parameters.("Mat_"+string(app.Parameters.models{1,i})+"_EVM"));
                        end
                    case "MSE"
                        app.Unique.YScale = "linear";
                        for i = 1:length(app.Parameters.models)
                            if strcmp(app.Parameters.models{1,i},"Perfect")
                                app.Parameters.("Mat_"+string(app.Parameters.models{1,i})+"_MSE") = zeros(size(app.Parameters.("Mat_"+string(app.Parameters.models{1,1})+"_MSE")));
                            end
                            plot(app.Unique,app.Parameters.SNR_Recorridas,app.Parameters.("Mat_"+string(app.Parameters.models{1,i})+"_MSE"));
                        end
                    case "Time"
                        app.Unique.YScale = "linear";
                end

                legend(app.Unique,app.Parameters.models)
            catch error
                message = error.message;
                id = error.identifier
                if (strcmp(id,'MATLAB:nonExistentField'))
                    uialert(app.UIFigure,['There is not data to plot '],"Invalid File","Icon","info");
                end

            end
        end

        % Button pushed function: ClearUnique
        function ClearUniqueButtonPushed(app, event)
            hold(app.Unique,"off");
            cla(app.Unique)
            app.Unique.XLimMode = "auto";
            app.Unique.YLimMode = "auto";
        end

        % Callback function
        function TimeSimulationCheckBoxValueChanged(app, event)
            app.Pam_sim.Time_Simulation = app.TimeSimulationCheckBox.Value;
            
        end

        % Button pushed function: SimulateTimeButton
        function SimulateTimeButtonPushed(app, event)
            % Basic Simulation in function of the input parameters

            app.TabGroup.SelectedTab = app.TimeSimulationTab;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %Simulation time 
                app.Label_2.Visible = "on";
                app.Label.Visible = "on";

                app.Model1_TimeLabel.Visible = "on";
                app.Model2_TimeLabel.Visible = "on";
                app.Model3_TimeLabel.Visible = "on";

                app.Model1_EVMLabel.Visible = "on";
                app.Model2_EVMLabel.Visible = "on";
                app.Model3_EVMLabel.Visible = "on";

                app.Model1_MSELabel.Visible = "on";
                app.Model2_MSELabel.Visible = "on";
                app.Model3_MSELabel.Visible = "on";

                app.Model1Label.Visible = "on";
                app.Model2Label.Visible = "on";
                app.Model3Label.Visible= "on";

                app.TimeLabel.Visible = "on";
                app.EVMLabel.Visible = "on";
                app.MSELabel.Visible = "on";

                
            %app = (app);


        end

        % Value changed function: Grafica1DropDown
        function Grafica1DropDownValueChanged(app, event)
            app.Pam_sim.Grap1_type = app.Grafica1DropDown.Value;
            app.Model1Label.Text = app.Pam_sim.Grap1_type;
            
            switch app.Pam_sim.Grap1_type
                case "CNN"
                    app.Graph1.Title.String = "Convolutional Neural Network Model " + app.Pam_sim.CNNmodel_1 ;
                case "CNN_2"
                    app.Graph1.Title.String = "Convolutional Neural Network Model " + app.Pam_sim.CNNmodel_2 ;
                case "Autoencoder"
                    app.Graph1.Title.String = "Autoencoder Model RNA"  ;
                case "Denoising"
                    app.Graph1.Title.String = "Denoising Deep Neuronal Model"  ;
                case "Practical"
                    app.Graph1.Title.String = "Practical Model from Matlab"  ;
                case "Perfect"
                    app.Graph1.Title.String = "Perfect Model from Matlab (Ideal from Noise)"  ;
                case "Lineal"
                     app.Graph1.Title.String = "Interpolation Model"  ;
            end


        end

        % Value changed function: Grafica2DropDown
        function Grafica2DropDownValueChanged(app, event)
            app.Pam_sim.Grap2_type = app.Grafica2DropDown.Value;
            app.Model2Label.Text = app.Pam_sim.Grap2_type;
            
            switch app.Pam_sim.Grap2_type
                case "CNN"
                    app.Graph2.Title.String = "Convolutional Neural Network Model " + app.Pam_sim.CNNmodel_1 ;
                case "CNN_2"
                    app.Graph2.Title.String = "Convolutional Neural Network Model " + app.Pam_sim.CNNmodel_2 ;
                case "Autoencoder"
                    app.Graph2.Title.String = "Autoencoder Model RNA"  ;
                case "Denoising"
                    app.Graph2.Title.String = "Denoising Deep Neuronal Model"  ;
                case "Practical"
                    app.Graph2.Title.String = "Practical Model from Matlab"  ;
                case "Perfect"
                    app.Graph2.Title.String = "Perfect Model from Matlab (Ideal from Noise)"  ;
                case "Lineal"
                    app.Graph2.Title.String = "Interpolation Model"  ;
            end
        end

        % Value changed function: Grafica3DropDown
        function Grafica3DropDownValueChanged(app, event)
            app.Pam_sim.Grap3_type = app.Grafica3DropDown.Value;
            app.Model3Label.Text = app.Pam_sim.Grap3_type;
            
            switch app.Pam_sim.Grap3_type
                case "CNN"
                    app.Graph3.Title.String = "Convolutional Neural Network Model " + app.Pam_sim.CNNmodel_1 ;
                case "CNN_2"
                    app.Graph3.Title.String = "Convolutional Neural Network Model " + app.Pam_sim.CNNmodel_2 ;
                case "Autoencoder"
                    app.Graph3.Title.String = "Autoencoder Model RNA"  ;
                case "Denoising"
                    app.Graph3.Title.String = "Denoising Deep Neuronal Model"  ;
                case "Practical"
                    app.Graph3.Title.String = "Practical Model from Matlab"  ;
                case "Perfect"
                    app.Graph3.Title.String = "Perfect Model from Matlab (Ideal from Noise)"  ;
                case "Lineal"
                    app.Graph3.Title.String = "Interpolation Model"  ;
            end
            
        end

        % Clicked callback: Grafica1DropDown
        function Grafica1DropDownClicked(app, event)
            item = event.InteractionInformation.Item;
        end

        % Value changed function: CNNModel1DropDown_2
        function CNNModel1DropDown_2ValueChanged(app, event)
            value = app.CNNModel1DropDown_2.Value;
            
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            try
            
            %% ------------------- SIMULATION CONFIGURATION ----------------------------
            %Get parameters from UI
            app.Pam_sim.Stop_RealTime = false;
            app.Pam_sim.Indice_Ranura = 0;
            while ~app.Pam_sim.Stop_RealTime
                drawnow
                app.Pam_sim.SNR = app.SNRdBKnob.Value;
                app.Pam_sim.Modulation = app.ModulationDropDown_2.Value;
                app.Pam_sim.Channel = app.DropDown_3.Value;
                app.Pam_sim.User_Velocity = app.UserVelocityKmhKnob.Value;
                app.Pam_sim.CNNmodel_1 = app.CNNModel1DropDown_2.Value;
                %%
                

                switch app.EstimationTypeDropDown.Value
                    case "Autoencoder"
                        app.Pam_sim.models = {'Lineal','Perfect'};
                        app.Pam_sim.models = [app.Pam_sim.models 'Autoencoder'];

                        app.Pam_sim.estimacionRNA_Autoencoder = load("Autoencoder.mat").estimacionRNA;
                    case "CNN"
                        app.Pam_sim.models = {'Lineal','Perfect'};
                        app.Pam_sim.models = [app.Pam_sim.models 'CNN'];
                        app.Pam_sim.estimacionRNA_1 = load("CNN_Modelo"+app.Pam_sim.CNNmodel_1+".mat").estimacionRNA;
                    case "Denoising"
                        app.Pam_sim.models = {'Lineal','Perfect'};
                        app.Pam_sim.estimacionRNA_Denoising= load("Denoising.mat").estimacionRNA;
                    case "Practical"
                        app.Pam_sim.models = {};
                        app.Pam_sim.models = [app.Pam_sim.models 'Practical'];

                    case "Perfect"
                        app.Pam_sim.models = {};
                        app.Pam_sim.models = [app.Pam_sim.models 'Perfect'];

                    case "Lineal"
                        app.Pam_sim.models = {};
                        app.Pam_sim.models = [app.Pam_sim.models 'Lineal'];

                end

                %% Ciclo de Procesamiento (Simulacion transmision en el tiempo )


                app.Pam_sim.SNR_Recorridas =[];

                %Inicialización y configuración de la portadora, Modulacion y canaldB
                % Parametros : Tipo de modulacion, Perfil de canal, Velocidad Usuario
                app.Pam_sim = Simulation_Configuration(app.Pam_sim);% Parametros de la simulacion

                app.Pam_sim =Variables_initialization(app.Pam_sim,app.Pam_sim.SNR);
                app.Pam_sim.SNR_Recorridas = [app.Pam_sim.SNR_Recorridas app.Pam_sim.SNR];

                switch app.Pam_sim.pdsch.Modulation
                    case "QPSK"
                        app.Pam_sim.bits_symbol = 2;
                    case "16QAM"
                        app.Pam_sim.bits_symbol = 4;
                    case "64QAM"
                        app.Pam_sim.bits_symbol = 6;
                    case "256QAM"
                        app.Pam_sim.bits_symbol = 8;
                end
                app.Pam_sim.symbols_ofdm_slot = 624*14;
                app.Pam_sim.symbols_info_ofdm_slot = length(app.Pam_sim.Indices_Pdsch);
                app.Pam_sim.symbols_info_ofdm_FRAME = 10*app.Pam_sim.symbols_info_ofdm_slot;

                app.Pam_sim.bits_per_slot = app.Pam_sim.symbols_ofdm_slot * app.Pam_sim.bits_symbol;
                app.Pam_sim.bits_info_per_slot = app.Pam_sim.symbols_info_ofdm_slot*app.Pam_sim.bits_symbol;

                app.Pam_sim.bits_info_per_FRAME = 10*app.Pam_sim.bits_info_per_slot;


                % UN SLOT CADA 1 ms
                app.Pam_sim.Total_Transmision_seg = app.Pam_sim.bits_per_slot/1e-3;
                app.Pam_sim.Total_Transmision_seg_Frame = app.Pam_sim.bits_info_per_FRAME/10e-3
                app.Pam_sim.Total_Transmision_info_seg = app.Pam_sim.bits_info_per_slot/1e-3;
                app.Pam_sim.real_throughput_FRAME = [];
                app.Pam_sim.Frames_transmitted = 0;

                
                app.Pam_sim.Indice_Ranura = app.Pam_sim.Indice_Ranura +1 ;
                disp(['Slot ',num2str(app.Pam_sim.Indice_Ranura ),' Enviado'])
                %% ------------ Transmisor  ----------------------------
                tStart_trans = tic;
                app.Pam_sim = Transmitter(app.Pam_sim);
                app.Pam_sim.tEnd_trans = toc(tStart_trans);
                %% ------------ Receptor (AWGN - CANAL TDL) ---------------------------
                tStart_recep = tic;
                app.Pam_sim = Receiver(app.Pam_sim);
                app.Pam_sim.tEnd_recep = toc(tStart_recep);
                %% ------------  Estimacion de Canal Lineal ---------------------------
                tStart_Lineal = tic;
                app.Pam_sim = Lineal_Estimation(app.Pam_sim);
                app.Pam_sim.tEnd_Lineal = toc(tStart_Lineal);
                %% ----------------- Estimacion de Canal Perfecta ---------------------
                tStart_Perfect = tic;
                app.Pam_sim = Perfect_Estimation(app.Pam_sim);
                app.Pam_sim.tEnd_Perfect = toc(tStart_Perfect);


                switch app.EstimationTypeDropDown.Value
                    case "Autoencoder"
                        tStart_Autoencoder= tic;
                        app.Pam_sim = Autoencoder_Estimation(app.Pam_sim,app.Pam_sim.estimacionRNA_Autoencoder);
                        app.Pam_sim.tEnd_Autoencoder = toc(tStart_Autoencoder);
                    case "CNN"
                        tStart_CNN = tic;
                        app.Pam_sim = CNN_Estimation(app.Pam_sim,app.Pam_sim.estimacionRNA_1);
                        app.Pam_sim.tEnd_CNN = toc(tStart_CNN);
                        app.Pam_sim.CNN_pdschEq_sin = app.Pam_sim.CNN_pdschEq ;
                    case "Denoising"
                        tStart_Denoising= tic;
                        app.Pam_sim = Denoising_Estimation(app.Pam_sim,app.Pam_sim.estimacionRNA_Denoising);
                        app.Pam_sim.tEnd_Denoising= toc(tStart_Denoising);
                    case "Practical"
                                           tStart_Practical = tic;
                    app.Pam_sim = Practical_Estimation(app.Pam_sim);
                    app.Pam_sim.tEnd_Practical = toc(tStart_Practical);
                    case "Perfect"
                    case "Lineal"
                end

                disp(['Slot ',num2str(app.Pam_sim.Indice_Ranura),' Procesado'])
                app.Pam_sim = Metricas_Slot(app.Pam_sim);
                if app.Pam_sim.Indice_Ranura>0
                    if mod(Indice_Ranura,10) == 0
                        Bits_slot_tx =  app.Pam_sim.txbits(end-length(app.Pam_sim.Indices_Pdsch)+1:end);
                        Bits_slot_rx = app.Pam_sim.Lineal_rxbits(end-length(app.Pam_sim.Indices_Pdsch)+1:end);
                        [Ber_frame,app.Pam_sim ] = Ber_Estimation(Bits_slot_tx,Bits_slot_rx,app.Pam_sim);
                        %[Ber_slot,app.Pam_sim ] = Ber_Estimation(Bits_slot_tx,Bits_slot_rx,app.Pam_sim);
                        Frames_transmitted = Frames_transmitted+1;
                        Bits_Utils_FRAME = bits_info_per_FRAME-app.Pam_sim.Wrong_Bits; % Bits transmitidos por frame
                        real_throughput_FRAME = Bits_Utils_FRAME/10e3;
                        app.Pam_sim.real_throughput_FRAME = [app.Pam_sim.real_throughput_FRAME real_throughput_FRAME];
                    end
                end


            end
            catch error
                uialert(app.UIFigure,['  There was an error! The message was:   ',error.message, ' the identifier was: ',error.identifier,],"Invalid File","Icon","error");
            end

            %% ------------------------------- END SIMULATION --------------------------------------

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.Pam_sim.Stop_RealTime = true;
            disp("Off");
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ParametrosTab = uitab(app.TabGroup);
            app.ParametrosTab.Title = 'Parametros';
            app.ChannelProfileDropDownLabel = uilabel(app.ParametrosTab);
            app.ChannelProfileDropDownLabel.Text = 'Channel Profile';
            app.ChannelProfileDropDown = uidropdown(app.ParametrosTab);
            app.ChannelProfileDropDown.Items = {'TDL-A', 'TDL-B', 'TDL-C', 'TDL-D', 'TDL-E'};
            app.ChannelProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelProfileDropDownValueChanged, true);
            app.ChannelProfileDropDown.Value = 'TDL-A';
            app.ModulationDropDownLabel = uilabel(app.ParametrosTab);
            app.ModulationDropDownLabel.Text = 'Modulation';
            app.ModulationDropDown = uidropdown(app.ParametrosTab);
            app.ModulationDropDown.Items = {'QPSK', '16QAM', '64QAM', '256QAM'};
            app.ModulationDropDown.ValueChangedFcn = createCallbackFcn(app, @ModulationDropDownValueChanged, true);
            app.ModulationDropDown.Value = 'QPSK';
            app.CNNModel1CheckBox = uicheckbox(app.ParametrosTab);
            app.CNNModel1CheckBox.ValueChangedFcn = createCallbackFcn(app, @CNNModel1CheckBoxValueChanged, true);
            app.CNNModel1CheckBox.Text = 'CNN Model 1';
            app.CNNModel1CheckBox.Value = true;
            app.CNNModel2CheckBox = uicheckbox(app.ParametrosTab);
            app.CNNModel2CheckBox.ValueChangedFcn = createCallbackFcn(app, @CNNModel2CheckBoxValueChanged, true);
            app.CNNModel2CheckBox.Text = 'CNN Model 2';
            app.AutoencoderCheckBox = uicheckbox(app.ParametrosTab);
            app.AutoencoderCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoencoderCheckBoxValueChanged, true);
            app.AutoencoderCheckBox.Text = 'Autoencoder';
            app.PracticalCheckBox = uicheckbox(app.ParametrosTab);
            app.PracticalCheckBox.ValueChangedFcn = createCallbackFcn(app, @PracticalCheckBoxValueChanged, true);
            app.PracticalCheckBox.Text = 'Practical';
            app.PracticalCheckBox.Value = true;
            app.Denoising1CheckBox = uicheckbox(app.ParametrosTab);
            app.Denoising1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Denoising1CheckBoxValueChanged, true);
            app.Denoising1CheckBox.Text = 'Denoising 1';
            app.Denoising2CheckBox = uicheckbox(app.ParametrosTab);
            app.Denoising2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Denoising2CheckBoxValueChanged, true);
            app.Denoising2CheckBox.Text = 'Denoising 2';
            app.EstimationLabel = uilabel(app.ParametrosTab);
            app.EstimationLabel.Text = 'Estimation';
            app.SimulateSimpleButton = uibutton(app.ParametrosTab, 'push');
            app.SimulateSimpleButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateSimpleButtonPushed, true);
            app.SimulateSimpleButton.Text = 'Simulate Simple';
            app.DataTrasmissionLabel = uilabel(app.ParametrosTab);
            app.DataTrasmissionLabel.Text = 'Data Trasmission';
            app.SNRdBLabel = uilabel(app.ParametrosTab);
            app.SNRdBLabel.Text = 'SNR (dB)';
            app.TypeSNRDropDownLabel = uilabel(app.ParametrosTab);
            app.TypeSNRDropDownLabel.Text = 'Type  SNR';
            app.TypeSNRDropDown = uidropdown(app.ParametrosTab);
            app.TypeSNRDropDown.Items = {'SNR Static', 'SNR Interval'};
            app.TypeSNRDropDown.DropDownOpeningFcn = createCallbackFcn(app, @TypeSNRDropDownOpening, true);
            app.TypeSNRDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeSNRDropDownValueChanged, true);
            app.TypeSNRDropDown.Value = 'SNR Static';
            app.DataDropDownLabel = uilabel(app.ParametrosTab);
            app.DataDropDownLabel.Text = 'Data';
            app.DataDropDown = uidropdown(app.ParametrosTab);
            app.DataDropDown.Items = {'Frames', 'Bits', 'Image'};
            app.DataDropDown.ValueChangedFcn = createCallbackFcn(app, @DataDropDownValueChanged, true);
            app.DataDropDown.Value = 'Frames';
            app.FramesNumberEditField = uieditfield(app.ParametrosTab, 'text');
            app.FramesNumberEditField.ValueChangedFcn = createCallbackFcn(app, @FramesNumberEditFieldValueChanged, true);
            app.FramesNumberEditField.Value = '1';
            app.LoadImageButton = uibutton(app.ParametrosTab, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Visible = 'off';
            app.LoadImageButton.Text = 'Load Image';
            app.FramesLabel = uilabel(app.ParametrosTab);
            app.FramesLabel.Text = 'Frames';
            app.SNRInitLabel = uilabel(app.ParametrosTab);
            app.SNRInitLabel.Text = 'SNR Init';
            app.StepLabel = uilabel(app.ParametrosTab);
            app.StepLabel.Visible = 'off';
            app.StepLabel.Text = 'Step';
            app.SNREndLabel = uilabel(app.ParametrosTab);
            app.SNREndLabel.Visible = 'off';
            app.SNREndLabel.Text = 'SNR End';
            app.Knob_SNR = uiknob(app.ParametrosTab, 'continuous');
            app.Knob_SNR.Limits = [0 40];
            app.Knob_SNR.ValueChangedFcn = createCallbackFcn(app, @Knob_SNRValueChanged, true);
            app.Knob_SNR.Visible = 'off';
            app.EditField_SNRinit = uieditfield(app.ParametrosTab, 'numeric');
            app.EditField_SNRinit.Limits = [0 40];
            app.EditField_SNRinit.RoundFractionalValues = 'on';
            app.EditField_SNRinit.ValueChangedFcn = createCallbackFcn(app, @EditField_SNRinitValueChanged, true);
            app.EditField_SNRinit.Value = 1;
            app.EditField_SNR_step = uieditfield(app.ParametrosTab, 'numeric');
            app.EditField_SNR_step.Limits = [0 40];
            app.EditField_SNR_step.ValueChangedFcn = createCallbackFcn(app, @EditField_SNR_stepValueChanged, true);
            app.EditField_SNR_step.Visible = 'off';
            app.EditField_SNR_step.Value = 1;
            app.Label_end_snr = uilabel(app.ParametrosTab);
            app.Label_end_snr.Visible = 'off';
            app.Label_end_snr.Text = '';
            app.VelocityKmhLabel = uilabel(app.ParametrosTab);
            app.VelocityKmhLabel.Text = 'Velocity (Km/h)';
            app.VelInitLabel = uilabel(app.ParametrosTab);
            app.VelInitLabel.Text = 'Vel Init';
            app.StepLabel_2 = uilabel(app.ParametrosTab);
            app.StepLabel_2.Visible = 'off';
            app.StepLabel_2.Text = 'Step';
            app.VelEndLabel = uilabel(app.ParametrosTab);
            app.VelEndLabel.Visible = 'off';
            app.VelEndLabel.Text = 'Vel End';
            app.Knob_Vel = uiknob(app.ParametrosTab, 'continuous');
            app.Knob_Vel.Limits = [0 150];
            app.Knob_Vel.ValueChangedFcn = createCallbackFcn(app, @Knob_VelValueChanged, true);
            app.Knob_Vel.Visible = 'off';
            app.EditField_vel_init = uieditfield(app.ParametrosTab, 'numeric');
            app.EditField_vel_init.Limits = [1 150];
            app.EditField_vel_init.RoundFractionalValues = 'on';
            app.EditField_vel_init.ValueChangedFcn = createCallbackFcn(app, @EditField_vel_initValueChanged, true);
            app.EditField_vel_init.Value = 1;
            app.EditField_vel_step = uieditfield(app.ParametrosTab, 'numeric');
            app.EditField_vel_step.Limits = [0 150];
            app.EditField_vel_step.ValueChangedFcn = createCallbackFcn(app, @EditField_vel_stepValueChanged, true);
            app.EditField_vel_step.Visible = 'off';
            app.EditField_vel_step.Value = 1;
            app.Label_end_vel = uilabel(app.ParametrosTab);
            app.Label_end_vel.Visible = 'off';
            app.Label_end_vel.Text = '';
            app.TypeVelocityDropDownLabel = uilabel(app.ParametrosTab);
            app.TypeVelocityDropDownLabel.Text = 'Type  Velocity';
            app.TypeVelocityDropDown = uidropdown(app.ParametrosTab);
            app.TypeVelocityDropDown.Items = {'Vel Static', 'Vel Interval'};
            app.TypeVelocityDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeVelocityDropDownValueChanged, true);
            app.TypeVelocityDropDown.Value = 'Vel Static';
            app.CNNModel2DropDown = uidropdown(app.ParametrosTab);
            app.CNNModel2DropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.CNNModel2DropDown.ValueChangedFcn = createCallbackFcn(app, @CNNModel2DropDownValueChanged, true);
            app.CNNModel2DropDown.Visible = 'off';
            app.CNNModel2DropDown.Value = '1';
            app.CNNModel1DropDown = uidropdown(app.ParametrosTab);
            app.CNNModel1DropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.CNNModel1DropDown.ValueChangedFcn = createCallbackFcn(app, @CNNModel1DropDownValueChanged, true);
            app.CNNModel1DropDown.Visible = 'off';
            app.CNNModel1DropDown.Value = '1';
            app.CNNModel1Label = uilabel(app.ParametrosTab);
            app.CNNModel1Label.Visible = 'off';
            app.CNNModel1Label.Text = 'CNN Model 1';
            app.CNNModel2Label = uilabel(app.ParametrosTab);
            app.CNNModel2Label.Visible = 'off';
            app.CNNModel2Label.Text = 'CNN Model 2 :';
            app.CNNPhaseCompensationCheckBox = uicheckbox(app.ParametrosTab);
            app.CNNPhaseCompensationCheckBox.ValueChangedFcn = createCallbackFcn(app, @CNNPhaseCompensationCheckBoxValueChanged, true);
            app.CNNPhaseCompensationCheckBox.Text = 'CNN Phase Compensation';
            app.ExportParametersCheckBox = uicheckbox(app.ParametrosTab);
            app.ExportParametersCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExportParametersCheckBoxValueChanged, true);
            app.ExportParametersCheckBox.Text = {'Export '; 'Parameters'};
            app.SimulateTimeButton = uibutton(app.ParametrosTab, 'push');
            app.SimulateTimeButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateTimeButtonPushed, true);
            app.SimulateTimeButton.Text = 'Simulate Time';
            app.TimeSimulationTab = uitab(app.TabGroup);
            app.TimeSimulationTab.Title = 'Time Simulation';
            app.Graph1 = uiaxes(app.TimeSimulationTab);
            title(app.Graph1, 'Title')
            xlabel(app.Graph1, 'X')
            ylabel(app.Graph1, 'Y')
            zlabel(app.Graph1, 'Z')
            app.Graph2 = uiaxes(app.TimeSimulationTab);
            title(app.Graph2, 'Title')
            xlabel(app.Graph2, 'X')
            ylabel(app.Graph2, 'Y')
            zlabel(app.Graph2, 'Z')
            app.Graph3 = uiaxes(app.TimeSimulationTab);
            title(app.Graph3, 'Title')
            xlabel(app.Graph3, 'X')
            ylabel(app.Graph3, 'Y')
            zlabel(app.Graph3, 'Z')
            app.Graph4 = uiaxes(app.TimeSimulationTab);
            title(app.Graph4, 'Title')
            xlabel(app.Graph4, 'X')
            ylabel(app.Graph4, 'Y')
            zlabel(app.Graph4, 'Z')
            app.Titulo_TimeLabel = uilabel(app.TimeSimulationTab);
            app.Titulo_TimeLabel.Text = 'Titulo_Time';
            app.Grafica1DropDownLabel = uilabel(app.TimeSimulationTab);
            app.Grafica1DropDownLabel.Text = 'Grafica 1';
            app.Grafica1DropDown = uidropdown(app.TimeSimulationTab);
            app.Grafica1DropDown.Items = {'CNN', 'CNN_2', 'Autoencoder', 'Denoising', 'Practical', 'Perfect', 'Lineal'};
            app.Grafica1DropDown.ValueChangedFcn = createCallbackFcn(app, @Grafica1DropDownValueChanged, true);
            app.Grafica1DropDown.ClickedFcn = createCallbackFcn(app, @Grafica1DropDownClicked, true);
            app.Grafica1DropDown.Value = 'CNN';
            app.Grafica2DropDownLabel = uilabel(app.TimeSimulationTab);
            app.Grafica2DropDownLabel.Text = 'Grafica 2';
            app.Grafica2DropDown = uidropdown(app.TimeSimulationTab);
            app.Grafica2DropDown.Items = {'Autoencoder', 'CNN', 'CNN_2', 'Denoising', 'Practical', 'Perfect', 'Lineal'};
            app.Grafica2DropDown.ValueChangedFcn = createCallbackFcn(app, @Grafica2DropDownValueChanged, true);
            app.Grafica2DropDown.Value = 'CNN';
            app.Grafica3DropDownLabel = uilabel(app.TimeSimulationTab);
            app.Grafica3DropDownLabel.Text = 'Grafica 3';
            app.Grafica3DropDown = uidropdown(app.TimeSimulationTab);
            app.Grafica3DropDown.Items = {'Practical', 'CNN', 'CNN_2', 'Autoencoder', 'Denoising', 'Perfect', 'Lineal'};
            app.Grafica3DropDown.ValueChangedFcn = createCallbackFcn(app, @Grafica3DropDownValueChanged, true);
            app.Grafica3DropDown.Value = 'CNN';
            app.MSELabel = uilabel(app.TimeSimulationTab);
            app.MSELabel.Visible = 'off';
            app.MSELabel.Text = 'MSE';
            app.EVMLabel = uilabel(app.TimeSimulationTab);
            app.EVMLabel.Visible = 'off';
            app.EVMLabel.Text = 'EVM(%)';
            app.TimeLabel = uilabel(app.TimeSimulationTab);
            app.TimeLabel.Visible = 'off';
            app.TimeLabel.Text = 'Time';
            app.StartButton = uibutton(app.TimeSimulationTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Model1Label = uilabel(app.TimeSimulationTab);
            app.Model1Label.Visible = 'off';
            app.Model1Label.Text = 'Model1';
            app.Model2Label = uilabel(app.TimeSimulationTab);
            app.Model2Label.Visible = 'off';
            app.Model2Label.Text = 'Model2';
            app.Model3Label = uilabel(app.TimeSimulationTab);
            app.Model3Label.Visible = 'off';
            app.Model3Label.Text = 'Model3';
            app.Model1_MSELabel = uilabel(app.TimeSimulationTab);
            app.Model1_MSELabel.Visible = 'off';
            app.Model1_MSELabel.Text = 'Model1_MSE';
            app.Model2_MSELabel = uilabel(app.TimeSimulationTab);
            app.Model2_MSELabel.Visible = 'off';
            app.Model2_MSELabel.Text = 'Model2_MSE';
            app.Model3_MSELabel = uilabel(app.TimeSimulationTab);
            app.Model3_MSELabel.Visible = 'off';
            app.Model3_MSELabel.Text = 'Model3_MSE';
            app.Model1_EVMLabel = uilabel(app.TimeSimulationTab);
            app.Model1_EVMLabel.Visible = 'off';
            app.Model1_EVMLabel.Text = 'Model1_EVM';
            app.Model2_EVMLabel = uilabel(app.TimeSimulationTab);
            app.Model2_EVMLabel.Visible = 'off';
            app.Model2_EVMLabel.Text = 'Model2_EVM';
            app.Model3_EVMLabel = uilabel(app.TimeSimulationTab);
            app.Model3_EVMLabel.Visible = 'off';
            app.Model3_EVMLabel.Text = 'Model3_EVM';
            app.Model1_TimeLabel = uilabel(app.TimeSimulationTab);
            app.Model1_TimeLabel.Visible = 'off';
            app.Model1_TimeLabel.Text = 'Model1_Time';
            app.Model2_TimeLabel = uilabel(app.TimeSimulationTab);
            app.Model2_TimeLabel.Visible = 'off';
            app.Model2_TimeLabel.Text = 'Model2_Time';
            app.Model3_TimeLabel = uilabel(app.TimeSimulationTab);
            app.Model3_TimeLabel.Visible = 'off';
            app.Model3_TimeLabel.Text = 'Model3_Time';
            app.Label = uilabel(app.TimeSimulationTab);
            app.Label.Visible = 'off';
            app.Label.Text = '_______________________________________________';
            app.Label_2 = uilabel(app.TimeSimulationTab);
            app.Label_2.Text = '';
            app.Metricas = uitab(app.TabGroup);
            app.Metricas.SizeChangedFcn = createCallbackFcn(app, @MetricasSizeChanged, true);
            app.Metricas.Title = 'Metricas';
            app.EVM = uiaxes(app.Metricas);
            title(app.EVM, 'Error vector Machine')
            xlabel(app.EVM, 'SNR (dB)')
            ylabel(app.EVM, 'EVM (%)')
            zlabel(app.EVM, 'Z')
            app.BER = uiaxes(app.Metricas);
            title(app.BER, 'Bit Error Rate')
            xlabel(app.BER, 'SNR (dB)')
            ylabel(app.BER, 'BER')
            zlabel(app.BER, 'Z')
            app.BER.YScale = 'log';
            app.MSE = uiaxes(app.Metricas);
            title(app.MSE, 'Mean Square Error')
            xlabel(app.MSE, 'SNR (dB)')
            ylabel(app.MSE, 'MSE')
            zlabel(app.MSE, 'Z')
            app.TIME = uiaxes(app.Metricas);
            title(app.TIME, 'Time')
            xlabel(app.TIME, 'X')
            ylabel(app.TIME, 'Y')
            zlabel(app.TIME, 'Z')
            app.Unique = uiaxes(app.Metricas);
            title(app.Unique, 'Graph')
            xlabel(app.Unique, 'x')
            ylabel(app.Unique, 'Y')
            zlabel(app.Unique, 'Z')
            app.Unique.Visible = 'off';
            app.ClearMSE = uibutton(app.Metricas, 'push');
            app.ClearMSE.ButtonPushedFcn = createCallbackFcn(app, @ClearMSEPushed, true);
            app.ClearMSE.Text = 'Clear';
            app.ClearEVM = uibutton(app.Metricas, 'push');
            app.ClearEVM.ButtonPushedFcn = createCallbackFcn(app, @ClearEVMPushed, true);
            app.ClearEVM.Text = 'Clear';
            app.ClearTIME = uibutton(app.Metricas, 'push');
            app.ClearTIME.ButtonPushedFcn = createCallbackFcn(app, @ClearTIMEPushed, true);
            app.ClearTIME.Text = 'Clear';
            app.ClearBER = uibutton(app.Metricas, 'push');
            app.ClearBER.ButtonPushedFcn = createCallbackFcn(app, @ClearBERPushed, true);
            app.ClearBER.Text = 'Clear';
            app.GraphMSE = uibutton(app.Metricas, 'push');
            app.GraphMSE.ButtonPushedFcn = createCallbackFcn(app, @GraphMSEPushed, true);
            app.GraphMSE.Text = 'Graph';
            app.GraphEVM = uibutton(app.Metricas, 'push');
            app.GraphEVM.ButtonPushedFcn = createCallbackFcn(app, @GraphEVMPushed, true);
            app.GraphEVM.Text = 'Graph';
            app.GraphTIME = uibutton(app.Metricas, 'push');
            app.GraphTIME.ButtonPushedFcn = createCallbackFcn(app, @GraphTIMEPushed, true);
            app.GraphTIME.Text = 'Graph';
            app.GraphBER = uibutton(app.Metricas, 'push');
            app.GraphBER.ButtonPushedFcn = createCallbackFcn(app, @GraphBERPushed, true);
            app.GraphBER.Text = 'Graph';
            app.GraphTypeDropDown = uidropdown(app.Metricas);
            app.GraphTypeDropDown.Items = {'Current Simulation', 'Load File'};
            app.GraphTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphTypeDropDownValueChanged, true);
            app.GraphTypeDropDown.Value = 'Current Simulation';
            app.SelectFileDropDown = uidropdown(app.Metricas);
            app.SelectFileDropDown.Items = {''};
            app.SelectFileDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectFileDropDownValueChanged, true);
            app.SelectFileDropDown.Visible = 'off';
            app.SelectFileDropDown.Value = '';
            app.SelectFileLabel = uilabel(app.Metricas);
            app.SelectFileLabel.Visible = 'off';
            app.SelectFileLabel.Text = 'Select File';
            app.GraphTypeLabel = uilabel(app.Metricas);
            app.GraphTypeLabel.Text = 'Graph Type ';
            app.DropDown = uidropdown(app.Metricas);
            app.DropDown.Items = {'Multiple', 'Unique'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged2, true);
            app.DropDown.Value = 'Multiple';
            app.StyleGraphLabel = uilabel(app.Metricas);
            app.StyleGraphLabel.Text = 'Style Graph';
            app.GraphLabel = uilabel(app.Metricas);
            app.GraphLabel.Visible = 'off';
            app.GraphLabel.Text = 'Graph :';
            app.DropDown_2 = uidropdown(app.Metricas);
            app.DropDown_2.Items = {'BER vs SNR', 'EVM vs SNR', 'MSE vs SNR', 'Time vs SNR'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Visible = 'off';
            app.DropDown_2.Value = 'BER vs SNR';
            app.GraphUnique = uibutton(app.Metricas, 'push');
            app.GraphUnique.ButtonPushedFcn = createCallbackFcn(app, @GraphUniqueButtonPushed, true);
            app.GraphUnique.Visible = 'off';
            app.GraphUnique.Text = 'Graph';
            app.ClearUnique = uibutton(app.Metricas, 'push');
            app.ClearUnique.ButtonPushedFcn = createCallbackFcn(app, @ClearUniqueButtonPushed, true);
            app.ClearUnique.Visible = 'off';
            app.ClearUnique.Text = 'Clear';
            app.RealTimeTab = uitab(app.TabGroup);
            app.RealTimeTab.Title = 'Real Time';
            app.Graph1_2 = uiaxes(app.RealTimeTab);
            title(app.Graph1_2, 'Channel Estimation ')
            xlabel(app.Graph1_2, 'X')
            ylabel(app.Graph1_2, 'Y')
            zlabel(app.Graph1_2, 'Z')
            app.Graph1_3 = uiaxes(app.RealTimeTab);
            title(app.Graph1_3, 'EVM ')
            xlabel(app.Graph1_3, 'Slot')
            ylabel(app.Graph1_3, 'Y')
            zlabel(app.Graph1_3, 'Z')
            app.Graph1_4 = uiaxes(app.RealTimeTab);
            title(app.Graph1_4, 'BER')
            xlabel(app.Graph1_4, 'Slot')
            ylabel(app.Graph1_4, 'Y')
            zlabel(app.Graph1_4, 'Z')
            app.ChannelTypeLabel = uilabel(app.RealTimeTab);
            app.ChannelTypeLabel.Text = 'Channel Type : ';
            app.DropDown_3 = uidropdown(app.RealTimeTab);
            app.DropDown_3.Items = {'TDL-A', 'TDL-B', 'TDL-C', 'TDL-D', 'TDL-E'};
            app.DropDown_3.Value = 'TDL-A';
            app.UserVelocityKmhKnobLabel = uilabel(app.RealTimeTab);
            app.UserVelocityKmhKnobLabel.Text = 'User Velocity (Km/h)';
            app.UserVelocityKmhKnob = uiknob(app.RealTimeTab, 'continuous');
            app.UserVelocityKmhKnob.Limits = [0 120];
            app.SNRdBKnobLabel = uilabel(app.RealTimeTab);
            app.SNRdBKnobLabel.Text = 'SNR (dB)';
            app.SNRdBKnob = uiknob(app.RealTimeTab, 'continuous');
            app.SNRdBKnob.Limits = [0 40];
            app.EstimationTypeDropDownLabel = uilabel(app.RealTimeTab);
            app.EstimationTypeDropDownLabel.Text = 'Estimation Type';
            app.EstimationTypeDropDown = uidropdown(app.RealTimeTab);
            app.EstimationTypeDropDown.Items = {'Autoencoder', 'CNN', 'Denoising', 'Practical', 'Perfect', 'Lineal'};
            app.EstimationTypeDropDown.Value = 'CNN';
            app.CNNModel1DropDown_2 = uidropdown(app.RealTimeTab);
            app.CNNModel1DropDown_2.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.CNNModel1DropDown_2.ValueChangedFcn = createCallbackFcn(app, @CNNModel1DropDown_2ValueChanged, true);
            app.CNNModel1DropDown_2.Visible = 'off';
            app.CNNModel1DropDown_2.Value = '1';
            app.CNNModelLabel = uilabel(app.RealTimeTab);
            app.CNNModelLabel.Visible = 'off';
            app.CNNModelLabel.Text = 'CNN Model';
            app.SimulateButton = uibutton(app.RealTimeTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.StopButton = uibutton(app.RealTimeTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Button = uibutton(app.RealTimeTab, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = 'Button';
            app.ModulationDropDown_2Label = uilabel(app.RealTimeTab);
            app.ModulationDropDown_2Label.Text = 'Modulation';
            app.ModulationDropDown_2 = uidropdown(app.RealTimeTab);
            app.ModulationDropDown_2.Items = {'16QAM', '64QAM', '256QAM', 'QPSK'};
            app.ModulationDropDown_2.Value = '16QAM';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.TabGroup.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.Menu_2 = uimenu(app.ContextMenu2);
            app.Menu_2.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu2);
            app.Menu2_2.Text = 'Menu2';
            % Assign app.ContextMenu2
            app.GraphEVM.ContextMenu = app.ContextMenu2;
            app.ContextMenu3 = uicontextmenu(app.UIFigure);
            app.Menu_3 = uimenu(app.ContextMenu3);
            app.Menu_3.Text = 'Menu';
            app.Menu2_3 = uimenu(app.ContextMenu3);
            app.Menu2_3.Text = 'Menu2';
            % Assign app.ContextMenu3
            app.SimulateSimpleButton.ContextMenu = app.ContextMenu3;
            app.SimulateTimeButton.ContextMenu = app.ContextMenu3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NR5G

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end